classdef Simu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ParamtresdelasimulationPanel    matlab.ui.container.Panel
        PlayButton                      matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        SurvirageLamp                   matlab.ui.control.Lamp
        SurvirageLampLabel              matlab.ui.control.Label
        SousvirageLamp                  matlab.ui.control.Lamp
        SousvirageLampLabel             matlab.ui.control.Label
        DuredelasimulationensEditField  matlab.ui.control.NumericEditField
        DuredelasimulationensEditFieldLabel  matlab.ui.control.Label
        PasdetempsensEditField          matlab.ui.control.NumericEditField
        PasdetempsensEditFieldLabel     matlab.ui.control.Label
        ConditionRouteButtonGroup       matlab.ui.container.ButtonGroup
        VerglasButton                   matlab.ui.control.RadioButton
        NeigeButton                     matlab.ui.control.RadioButton
        MouillButton                    matlab.ui.control.RadioButton
        SecButton                       matlab.ui.control.RadioButton
        SituationSouhaiteButtonGroup    matlab.ui.container.ButtonGroup
        VitesseconstanteButton          matlab.ui.control.RadioButton
        GuidonnageButton                matlab.ui.control.RadioButton
        EvitementdobstaclegaucheButton  matlab.ui.control.RadioButton
        FreinageBrusqueButton           matlab.ui.control.RadioButton
        VirageNormalButton              matlab.ui.control.RadioButton
        AngledeBraquageVolantenSlider   matlab.ui.control.Slider
        AngledeBraquageVolantenSliderLabel  matlab.ui.control.Label
        AngledeBraquagedesrouesenSlider  matlab.ui.control.Slider
        AngledeBraquagedesrouesenSliderLabel  matlab.ui.control.Label
        VitesseenkmhSlider              matlab.ui.control.Slider
        VitesseenkmhLabel               matlab.ui.control.Label
        OptionvehiculeButtonGroup       matlab.ui.container.ButtonGroup
        MercedesClasseSButton           matlab.ui.control.RadioButton
        QuadMF400HButton                matlab.ui.control.RadioButton
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        UIAxes10                        matlab.ui.control.UIAxes
        UIAxes9                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Vitesse = 0; % Définition initiale de la propriété % Description
        VitesseEditField; % Déclaration de la propriété VitesseEditField
        AngleDeBraquage=0;
        AngledebraquageEditField;
        Angledebraquagevolant=0;
        AngledebraquagevolantEditField;
        VirageNormale=0;
        selectedButton matlab.ui.control.RadioButton
        selectedState = 0;
        dt=0;
        Tf=0;
    end
    
    methods (Access = private)
        
      
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: VitesseenkmhSlider
        function VitesseenkmhSliderValueChanged(app, event)
            value = app.VitesseenkmhSlider.Value;
            app.Vitesse = value;
            app.VitesseEditField.Value = value;
        end

        % Value changed function: AngledeBraquagedesrouesenSlider
        function AngledeBraquagedesrouesenSliderValueChanged(app, event)
            valueA = app.AngledeBraquagedesrouesenSlider.Value;
            app.AngleDeBraquage = valueA;
            app.AngledebraquageEditField.Value = valueA;
            valueB = valueA * 20; % Multiplication par le facteur 20 (échelle différente)
            app.AngledeBraquageVolantenSlider.Value = valueB;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            %Eteindre les lampes
            app.SousvirageLamp.Color=[0.5, 0.5, 0.5];
            app.SurvirageLamp.Color=[0.5, 0.5, 0.5];

            % Paramètres du véhicule
            if app.QuadMF400HButton.Value==1
                m = 414; % Masse (kg)
                L = 1.28; % Empattement (m)
                Lf = 0.58; % Distance du centre de gravité à l'avant (m)
                Lr = 0.7; % Distance du centre de gravité à l'arrière (m)
                Cr = 63460; % Coefficient de frottement à l'arrière (N/rad/s)
                Cf = 69740; % Coefficient de frottement à l'avant (N/rad/s)
                Iz = 176; % Moment d'inertie autour de l'axe vertical passant par le centre de gravité (kg.m^2)
            elseif app.MercedesClasseSButton.Value==1
                % Paramètres pour une Mercedes Classe S
                m = 2000; % Masse (kg)
                L = 2.85; % Empattement (m)
                Lf = 1.4; % Distance du centre de gravité à l'avant (m)
                Lr = 1.45; % Distance du centre de gravité à l'arrière (m)
                Cr = 65000; % Coefficient de frottement à l'arrière (N/rad/s)
                Cf = 75000; % Coefficient de frottement à l'avant (N/rad/s)
                Iz = 1750; % Moment d'inertie autour de l'axe vertical passant par le centre de gravité (kg.m^2)
            end

            if app.SecButton.Value == 1 % Conditions de conduite sèche activée
                Cr = Cr * 1; % Aucun changement, maintient le coefficient de friction arrière (Cr)
                Cf = Cf * 1; % Aucun changement, maintient le coefficient de friction avant (Cf)
            elseif app.MouillButton.Value == 1 % Conditions de conduite humide activée
                Cr = Cr * 0.7; % Réduit le coefficient de friction arrière (Cr) de 30%
                Cf = Cf * 0.7; % Réduit le coefficient de friction avant (Cf) de 30%
            elseif app.NeigeButton.Value == 1 % Conditions de conduite enneigée activée
                Cr = Cr * 0.3; % Réduit fortement le coefficient de friction arrière (Cr) à 30%
                Cf = Cf * 0.3; % Réduit fortement le coefficient de friction avant (Cf) à 30%
            elseif app.VerglasButton.Value == 1 % Conditions de conduite verglacée activée
                Cr = Cr * 0.1; % Réduit extrêmement le coefficient de friction arrière (Cr) à 10%
                Cf = Cf * 0.1; % Réduit extrêmement le coefficient de friction avant (Cf) à 10%
            end


            % Préparation des données de simulation
            dt = app.dt; % Pas de temps (s)
            Tf = app.Tf; % Durée de la simulation (s)
            if dt == 0
                errordlg('Veuillez entrer une valeur numérique positive pour le pas de temps.', 'Erreur de saisie');
                return; % Arrête l'exécution de la fonction
            end

            if Tf == 0
                errordlg('Veuillez entrer une valeur numérique positive pour la durée de simulation.', 'Erreur de saisie');
                return; % Arrête l'exécution de la fonction
            end
            T_ = 0:dt:Tf; % Vecteur temps
            NbrPoint=length(T_); % Nombre de points
            Tc_beta = 4; % Temps pour atteindre l'angle de braquage maximum en secondes
            Tc_u = 4; % Temps pour atteindre la vitesse maximale en secondes
            Temps=T_;

            %Paramètre d'entrées
            % Demande à l'utilisateur de spécifier la vitesse linéaire et l'angle de braquage
            vitesse = app.Vitesse;
            angleBraquage = app.AngleDeBraquage;
            % Convertit la vitesse linéaire de km/h à m/s
            vitesse = vitesse * (1000 / 3600);
            % Convertit l'angle de braquage de degrés à radian
            angleBraquage = deg2rad(angleBraquage);
            % Remplit les vecteurs d'entrée avec les valeurs spécifiées par l'utilisateur
            u_ = vitesse * ones(size(T_));
            Beta_ = angleBraquage * ones(size(T_));
            %Défintion d'une vitesse limite
            Ulim = 5 ;
            ulim = Ulim / 3.6; % Vitesselimitedecinmatique en m. s−1

            % Initialisation des variables
            Vbeta = zeros(NbrPoint, 1);
            Vu = zeros(NbrPoint, 1);
            if app.VirageNormalButton.Value==1
                % Construction des entrées de vitesse et d'angle de braquage
                for i = 1:NbrPoint
                    Vbeta(i) = Beta_(i) * (Temps(i) / Tc_beta) / sqrt(1 + (Temps(i) / Tc_beta)^2);
                    Vu(i) = u_(i) * (Temps(i) / Tc_u) / sqrt(1 + (Temps(i) / Tc_u)^2);
                end
            elseif app.FreinageBrusqueButton.Value==1
                % Construction des entrées avec freinage brusque
                for i = 1:int64(8 / dt)
                    Vu(i) = u_(i) * (Temps(i) / Tc_u) / sqrt(1 + (Temps(i) / Tc_u)^2);
                end
                % Calcul du freinage moins brusque
                deceleration_rate = 0.001; % Modifier le taux de décélération selon votre besoin
                for i = int64(8 / dt):NbrPoint
                    Vu(i) = max(0, Vu(i-1) - deceleration_rate * u_(i) * ((Temps(i) - 8) / Tc_u) / sqrt(1 + ((Temps(i) - 8) / Tc_u)^2));
                end
                for i = 1:NbrPoint
                    Vbeta(i) = Beta_(i) * (Temps(i) / Tc_beta) / sqrt(1 + (Temps(i) / Tc_beta)^2);
                end
            elseif app.EvitementdobstaclegaucheButton.Value==1
                obstacle_time = 2; % Temps auquel l'obstacle apparaît (à titre d'exemple)
                avoidance_time = 6; % Temps auquel l'évitement commence (à titre d'exemple)

                for i = 1:NbrPoint
                    Vbeta(i) = Beta_(i) * (Temps(i) / Tc_beta) / sqrt(1 + (Temps(i) / Tc_beta)^2);
                    Vu(i) = u_(i) * (Temps(i) / Tc_u) / sqrt(1 + (Temps(i) / Tc_u)^2);

                    % Simulation de l'évitement d'obstacle à partir d'un certain moment
                    if Temps(i) >= obstacle_time
                        if Temps(i) >= avoidance_time
                            Vbeta(i) = Vbeta(i) * 2; % Augmenter temporairement l'angle de braquage
                            Vu(i) = Vu(i) ; % Augmenter temporairement la vitesse
                        end
                    end
                end
            elseif app.GuidonnageButton.Value == 1
                % Simuler un comportement de guidonnage ou d'instabilité du volant
                instability_time = 5; % Temps auquel le guidonnage commence (à titre d'exemple)
                oscillation_frequency = 0.5; % Fréquence des oscillations du guidonnage (à titre d'exemple)

                for i = 1:NbrPoint
                    Vbeta(i) = Beta_(i) * (Temps(i) / Tc_beta) / sqrt(1 + (Temps(i) / Tc_beta)^2);
                    Vu(i) = u_(i) * (Temps(i) / Tc_u) / sqrt(1 + (Temps(i) / Tc_u)^2);

                    % Simulation du guidonnage à partir d'un certain moment
                    if Temps(i) >= instability_time
                        % À partir du temps de guidonnage, introduisez des oscillations incontrôlées dans l'angle de braquage
                        % Voici un exemple de modélisation d'oscillations sinusoïdales :
                        amplitude = 0.1; % Amplitude des oscillations du guidonnage
                        Vbeta(i) = Vbeta(i) + amplitude * sin(2 * pi * oscillation_frequency * Temps(i));
                        % Vous pouvez ajuster l'amplitude et la fréquence pour modifier l'effet du guidonnage
                    end
                end
            elseif app.VitesseconstanteButton.Value == 1
                % Construction des entrées pour un virage à vitesse constante
                for i = 1:NbrPoint
                    Vu(i) = u_(i);
                    if Temps(i) >= 5 && Temps(i) <= 13
                        Vbeta(i) = Beta_(i) * ((Temps(i) - t0) / 2) / sqrt(1 + ((Temps(i) - t0) / 2)^2);
                    elseif Temps(i) < 5
                        t0 = Temps(i);
                    end
                end
            end

            % Initialisation des variables
            Thetapp_0 = 0;
            Thetap_0 = 0;
            Theta_0 = 0;
            Deltap_0 = 0;
            Delta_0 = (Lr / (Lr + Lf)) * Vbeta(1);
            Deltaf_0 = 0;
            Deltar_0 = 0;
            Deltaf_sat = 5;
            Deltar_sat = 5;
            da_0 = abs(Deltaf_0) - abs(Deltar_0);

            % Initialisation des variables de position
            Bx_0 = 0;
            By_0 = 0;
            Gx_0 = Bx_0 + Lr * cos(Theta_0 + Delta_0);
            Gy_0 = By_0 + Lr * sin(Theta_0 + Delta_0);
            Ax_0 = Bx_0 + (Lr + Lf) * cos(Theta_0 + Beta_(1) + Delta_0);
            Ay_0 = By_0 + (Lr + Lf) * sin(Theta_0 + Beta_(1) + Delta_0);

            % Initialisation des variables de vitesse en A, B et G selon x et y
            VBx_0 = Vu(1);
            VBy_0 = 0;
            VB_0 = Vu(1); % Norme de la vitesse sur la roue arrière
            VGx_0 = 0;
            VGy_0 = 0;
            VAx_0 = 0;
            VAy_0 = 0;

            % Initialisation des variables d'accélération en A, B et G selon x et y
            ABx_0 = 0;
            ABy_0 = 0;
            AGx_0 = 0;
            AGy_0 = 0;
            AG_0 = 0;
            AAx_0 = 0;
            AAy_0 = 0;

            % Initialisation des vecteurs de variables d'état
            Thetapp = zeros(NbrPoint, 1);
            Thetap = zeros(NbrPoint, 1);
            Theta = zeros(NbrPoint, 1);
            Thetapp(1) = Thetapp_0 * 180 / pi;
            Thetap(1) = Thetap_0 * 180 / pi;
            Theta(1) = Theta_0 * 180 / pi;

            Deltap = zeros(NbrPoint, 1);
            Delta = zeros(NbrPoint, 1);
            Delta_r = zeros(NbrPoint, 1);
            Delta_f = zeros(NbrPoint, 1);
            DA = zeros(NbrPoint, 1);
            Deltap(1) = Deltap_0 * 180 / pi;
            Delta(1) = Delta_0 * 180 / pi;
            DA(1) = da_0;

            Ff = zeros(NbrPoint, 1);
            Fr = zeros(NbrPoint, 1);
            Ff_sat = zeros(NbrPoint, 1);
            Fr_sat = zeros(NbrPoint, 1);

            R = zeros(NbrPoint, 1);

            Bx = zeros(NbrPoint, 1);
            By = zeros(NbrPoint, 1);
            Gx = zeros(NbrPoint, 1);
            Gy = zeros(NbrPoint, 1);
            Ax = zeros(NbrPoint, 1);
            Ay = zeros(NbrPoint, 1);
            Bx(1) = Bx_0;
            By(1) = By_0;
            Gx(1) = Gx_0;
            Gy(1) = Gy_0;
            Ax(1) = Ax_0;
            Ay(1) = Ay_0;

            VBx = zeros(NbrPoint, 1);
            VBy = zeros(NbrPoint, 1);
            VGx = zeros(NbrPoint, 1);
            VGy = zeros(NbrPoint, 1);
            VAx = zeros(NbrPoint, 1);
            VAy = zeros(NbrPoint, 1);
            VBx(1) = VBx_0 * 3.6; % Conversion en km/h
            VBy(1) = VBy_0 * 3.6;
            VGx(1) = VGx_0 * 3.6;
            VGy(1) = VGy_0 * 3.6;
            VAx(1) = VAx_0 * 3.6;
            VAy(1) = VAy_0 * 3.6;

            ABx = zeros(NbrPoint, 1);
            ABy = zeros(NbrPoint, 1);
            AGx = zeros(NbrPoint, 1);
            AGy = zeros(NbrPoint, 1);
            AAx = zeros(NbrPoint, 1);
            AAy = zeros(NbrPoint, 1);

            cpt=0;

            for i = 2:NbrPoint
                if Vu(i) <= ulim
                    % Calcul des angles de lacet et de dérive globaux
                    Thetap_1 = Vu(i) * tan(Vbeta(i)) / (Lr + Lf);
                    Theta_1 = Theta_0 + dt * Thetap_1;
                    Delta_1 = (Lr / (Lr + Lf)) * Vbeta(i);
                    % Calcul des vitesses
                    VBx_1 = Vu(i);
                    VBy_1 = 0;
                    VGx_1 = VBx_1 / cos(Delta_1);
                    VGy_1 = Lr * Thetap_1 / sin(Delta_1);
                    VAx_1 = VBx_1 / cos(Vbeta(i));
                    VAy_1 = (Lr + Lf) * Thetap_1 / sin(Vbeta(i));

                    % Calcul du rayon de courbure
                    [Deltar_1, Deltaf_1] = AngleDeriveAvantArriere(Delta_1, Thetap_1, ...
                        Vbeta(i), Vu(i), Lf, Lr);
                    R_1 = L/(Vbeta(i)+ Deltaf_1- Deltar_1);
                    R(i) = R_1;
                    R_0 = R_1;

                    da_1 = abs(Deltaf_1) - abs(Deltar_1);
                    DA(i) = da_1 * 180 / pi;
                    % Calcul des positions
                    Bx_1 = Bx_0 + VBx_1 * cos(Theta_1 + Vbeta(i)) * dt;
                    By_1 = By_0 + VBx_1 * sin(Theta_1 + Vbeta(i)) * dt;
                    Gx_1 = Gx_0 + VGx_1 * cos(Theta_1 + Vbeta(i)) * dt;
                    Gy_1 = Gy_0 + VGy_1 * sin(Theta_1 + Vbeta(i)) * dt;
                    Ax_1 = Ax_0 + VAx_1 * cos(Theta_1 + Vbeta(i) + Deltaf_0) * dt;
                    Ay_1 = Ay_0 + VAy_1 * sin(Theta_1 + Vbeta(i) + Deltaf_0) * dt;

                    Bx(i) = Bx_1;
                    By(i) = By_1;
                    Gx(i) = Gx_1;
                    Gy(i) = Gy_1;
                    Ax(i) = Ax_1;
                    Ay(i) = Ay_1;

                    % Calcul des accélérations
                    ABx_1 = (Vu(i) - Vu(i-1)) / dt;
                    ABy_1 = Vu(i) * Thetap_1;
                    AGx_1 = -Lr * Thetap_1^2 / sin(Delta_1);
                    AGy_1 = Vu(i) * Thetap_1 / cos(Delta_1);
                    AAx_1 = -(Lr + Lf) * Thetap_1^2 / sin(Vbeta(i));
                    AAy_1 = Vu(i) * Thetap_1 / cos(Vbeta(i));

                    ABx(i) = ABx_1 / 9.81;
                    ABy(i) = ABy_1 / 9.81;
                    AGx(i) = AGx_1 / 9.81;
                    AGy(i) = AGy_1 / 9.81;
                    AAx(i) = AAx_1 / 9.81;
                    AAy(i) = AAy_1 / 9.81;

                    Delta_r(i) = Deltar_1 * 180 / pi;
                    Delta_f(i) = Deltaf_1 * 180 / pi;


                    % Mise à jour des variables
                    Thetap_0 = Thetap_1;
                    Theta_0 = Theta_1;

                    Deltaf_0 = Deltaf_1;
                    Deltar_0 = Deltar_1;

                    Delta_0 = Delta_1;

                    Bx_0 = Bx_1;
                    By_0 = By_1;
                    Gx_0 = Gx_1;
                    Gy_0 = Gy_1;
                    Ax_0 = Ax_1;
                    Ay_0 = Ay_1;

                    VBx_0 = VBx_1;
                    VBy_0 = VBy_1;
                    VGx_0 = VGx_1;
                    VGy_0 = VGy_1;
                    VAx_0 = VAx_1;
                    VAy_0 = VAy_1;

                    ABx_0 = ABx_1;
                    ABy_0 = ABy_1;
                    AGx_0 = AGx_1;
                    AGy_0 = AGy_1;
                    AAx_0 = AAx_1;
                    AAy_0 = AAy_1;

                else
                    % Calcul des coefficients de la matrice A et du vecteur B
                    [A, B] = Coefficient(m, Iz, Vu(i), Lf, Lr, Cf, Cr);
                    % Calcul des angles de dérive et de lacet
                    [Thetapp_1, Thetap_1, Theta_1, Deltap_1, Delta_1] = ...
                        AngleLacetDerive(Thetap_0, Theta_0, Delta_0, Vbeta(i), A, B, dt);
                    [Deltar_1, Deltaf_1] = AngleDeriveAvantArriere(Delta_1, Thetap_1, ...
                        Vbeta(i), Vu(i), Lf, Lr);
                    % Calcul des forces Ff et Fr
                    Ff_1 = -Cf * Deltaf_1;
                    Fr_1 = -Cr * Deltar_1;
                    % Saturation des forces
                    if abs(Deltaf_1) > Deltaf_sat * pi / 180
                        Ffsat_1 = -Cf * Deltaf_sat * sign(Deltaf_1) * pi / 180;
                    else
                        Ffsat_1 = -Cf * Deltaf_1;
                    end
                    if abs(Deltar_1) > Deltar_sat * pi / 180
                        Frsat_1 = -Cr * Deltar_sat * sign(Deltar_1) * pi / 180;
                    else
                        Frsat_1 = -Cr * Deltar_1;
                    end
                    % Calcul des positions des roues Avant (A) et Arrière (B) et du Centre de gravité (G)
                    Bx_1 = Bx_0 + Vu(i) * dt * cos(Theta_1 + Deltar_1);
                    By_1 = By_0 + Vu(i) * dt * sin(Theta_1 + Deltar_1);
                    [Gx_1, Gy_1, Ax_1, Ay_1] = Position(Bx_1, By_1, Theta_1, Deltaf_1, Delta_1, Lf, Lr);
                    % Calcul des vitesses en A, B et G
                    [VGx_1, VGy_1] = VitesseEnG(Vu(i), Delta_1);
                    [VAx_1, VAy_1, VBx_1, VBy_1] = VitesseAvantArriere(Vu(i), Delta_1, ...
                        Deltaf_1, Deltar_1, Vbeta(i), Thetap_1, Lf, Lr);
                    % Calcul des accélérations en A, B et G
                    AGx_1 = (-Ff_1 * sin(Vbeta(i))) / m;
                    AGy_1 = (Fr_1 + Ff_1 * cos(Vbeta(i))) / m;
                    AG_1 = sqrt(AGx_1^2 + AGy_1^2);
                    AAx_1 = (VAx_1 - VAx_0) / dt;
                    AAy_1 = (VAy_1 - VAy_0) / dt;
                    ABx_1 = (VBx_1 - VBx_0) / dt;
                    ABy_1 = (VBy_1 - VBy_0) / dt;
                    % Calcul du rayon de courbure
                    VB_1 = sqrt(VBx_1^2 + VBy_1^2);
                    R_1 = L/(Vbeta(i)+ Deltaf_1- Deltar_1);
                    % Calcul de la différence entre les angles de dérive avant et arrière
                    da_1 = abs(Deltaf_1) - abs(Deltar_1);
                    if da_1>0
                        Cf=Cf*max((1-cpt), 1/Vu(i));
                        cpt=cpt+0.00000001;
                    end
                    if da_1>2
                        Cf=Cf*(1-cpt);
                        cpt=cpt+0.01;
                    end
                    if da_1<0
                        Cr=Cr*max((1-cpt), 1/Vu(i));
                        cpt=cpt+0.00000001;
                    end
                    % Incorporation dans les vecteurs de sortie
                    Thetapp(i) = Thetapp_1 * 180 / pi;
                    Thetap(i) = Thetap_1 * 180 / pi;
                    Theta(i) = Theta_1 * 180 / pi;
                    Deltap(i) = Deltap_1 * 180 / pi;
                    Delta(i) = Delta_1 * 180 / pi;
                    Delta_r(i) = Deltar_1 * 180 / pi;
                    Delta_f(i) = Deltaf_1 * 180 / pi;
                    DA(i) = da_1 * 180 / pi;
                    Ff(i) = Ff_1;
                    Fr(i) = Fr_1;
                    Ff_sat(i) = Ffsat_1;
                    Fr_sat(i) = Frsat_1;
                    Bx(i) = Bx_1;
                    By(i) = By_1;
                    Gx(i) = Gx_1;
                    Gy(i) = Gy_1;
                    Ax(i) = Ax_1;
                    Ay(i) = Ay_1;
                    VBx(i) = VBx_1 * 3.6;
                    VBy(i) = VBy_1 * 3.6;
                    VGx(i) = VGx_1 * 3.6;
                    VGy(i) = VGy_1 * 3.6;
                    VAx(i) = VAx_1 * 3.6;
                    VAy(i) = VAy_1 * 3.6;
                    ABx(i) = ABx_1 / 9.81;
                    ABy(i) = ABy_1 / 9.81;
                    AGx(i) = AGx_1 / 9.81;
                    AGy(i) = AGy_1 / 9.81;
                    AG(i) = AG_1 / 9.81;
                    AAx(i) = AAx_1 / 9.81;
                    AAy(i) = AAy_1 / 9.81;
                    R(i) = R_1;
                    % Mise à jour des variables
                    Thetapp_0 = Thetapp_1;
                    Thetap_0 = Thetap_1;
                    Theta_0 = Theta_1;
                    Delta_0 = Delta_1;
                    Deltaf_0 = Deltaf_1;
                    Deltar_0 = Deltar_1;
                    da_0 = da_1;
                    Ff_0 = Ff_1;
                    Fr_0 = Fr_1;
                    Frsat_0 = Frsat_1;
                    Ffsat_0 = Ffsat_1;
                    Bx_0 = Bx_1 ;
                    By_0 = By_1 ;
                    Gx_0 = Gx_1;
                    Gy_0 = Gy_1;
                    Ax_0 = Ax_1 ;
                    Ay_0 = Ay_1 ;
                    R_0 = R_1;
                    VBx_0 = VBx_1;
                    VBy_0 = VBy_1;
                    VB_0 = VB_1;
                    VGx_0 = VGx_1;
                    VGy_0 = VGy_1;
                    VAx_0 = VAx_1;
                    VAy_0 = VAy_1;
                    ABx_0 = ABx_1;
                    ABy_0 = ABy_1;
                    AGx_0 = AGx_1;
                    AGy_0 = AGy_1;
                    AG_0 = AG_1;
                    AAx_0 = AAx_1;
                    AAy_0 = AAy_1;
                end
            end

            % Définition des sorties
            UIaxes1=app.UIAxes;
            UIaxes2=app.UIAxes2;
            UIaxes3=app.UIAxes3;
            UIaxes4=app.UIAxes4;
            UIaxes5=app.UIAxes5;
            %UIaxes6=app.UIAxes6;
            %UIaxes7=app.UIAxes7;
            %UIaxes8=app.UIAxes8;
            UIaxes9=app.UIAxes9;
            UIaxes10=app.UIAxes10;

            % Sorties affichages
            % Création du graphique avec les deux axes y
            yyaxis(app.UIAxes, 'left');
            plot(app.UIAxes, Temps, Delta);
            ylabel(app.UIAxes, 'Angle (deg)');
            hold(app.UIAxes, 'on');
            yyaxis(app.UIAxes, 'right');
            plot(app.UIAxes, Temps, Thetap);
            ylabel(app.UIAxes, 'Vitesse Angulaire Lacet (deg/s)');
            xlabel(app.UIAxes, 'Temps (s)');
            title(app.UIAxes, 'Angle de dérive global et vitesse angulaire Lacet');
            legend(app.UIAxes, 'Angle de dérive global', 'Vitesse angulaire Lacet');
            hold(app.UIAxes, 'off');


            hold(app.UIAxes2, 'on');
            plot(app.UIAxes2, Temps, Delta_f);
            plot(app.UIAxes2, Temps, Delta_r);
            plot(app.UIAxes2, Temps, Delta, 'LineStyle', '--');
            xlabel(app.UIAxes2, 'Temps (s)');
            ylabel(app.UIAxes2, 'Angle (deg)');
            title(app.UIAxes2, 'Angle de dérive avant, arrière et global');
            legend(app.UIAxes2, 'Angle de dérive avant', 'Angle de dérive arrière', 'Angle de dérive Global');
            hold(app.UIAxes2, 'off');

            hold(app.UIAxes10, 'on');
            plot(app.UIAxes10,  Ax, Ay, Gx, Gy, Bx, By);
            xlabel(app.UIAxes10,  "Position en x (m)");
            ylabel(app.UIAxes10,  "Position en y (m)");
            title(app.UIAxes10,  "Position des roues et du centre de gravité");
            legend(app.UIAxes10,  "Position roue avant", "Position centre de gravité", "Position roue arrière");
            axis(app.UIAxes10,  'equal');
            hold(app.UIAxes10,  'off');

            plot(app.UIAxes3, Temps, Vu * 3.6)
            xlabel(app.UIAxes3, 'Temps (s)')
            ylabel(app.UIAxes3, 'Vitesse linéaire (km/h)')
            title(app.UIAxes3, 'Vitesse d''entrée')
            legend(app.UIAxes3, 'off')

            plot(app.UIAxes4, Temps, Vbeta * 180 / pi)
            xlabel(app.UIAxes4, 'Temps (s)')
            ylabel(app.UIAxes4, 'Angle de braquage (deg)')
            title(app.UIAxes4, 'Angle de braquage en entrée')
            legend(app.UIAxes4, 'off')

            plot(app.UIAxes5, Temps, DA)
            xlabel(app.UIAxes5, 'Temps (s)')
            ylabel(app.UIAxes5, 'Angle (deg)')
            title(app.UIAxes5, 'Différence des angles de dérive avant et arrière')
            legend(app.UIAxes5, 'off')

            % plot(app.UIAxes6, Temps, AGx, Temps, AGy)
            % xlabel(app.UIAxes6, 'Temps (s)')
            % ylabel(app.UIAxes6, 'Accélération (G)')
            % title(app.UIAxes6, 'Accélération au centre de gravité en fonction du temps')
            % legend(app.UIAxes6, 'Accélération longitudinale', 'Accélération transversale')

            % plot(app.UIAxes7, Temps, R)
            % xlabel(app.UIAxes7, 'Temps (s)')
            % ylabel(app.UIAxes7, 'Rayon de courbure (m)')
            % title(app.UIAxes7, 'Distance du rayon de courbure')
            % legend(app.UIAxes7, 'off')
            %
            % plot(app.UIAxes8, Delta_f * pi / 180, Ff, Delta_r * pi / 180, Fr)
            % xlabel(app.UIAxes8, 'Angle de dérive (rad)')
            % ylabel(app.UIAxes8, 'Force de dérive latérale (N)')
            % title(app.UIAxes8, 'Force de dérive latérale avant et arrière')
            % legend(app.UIAxes8, 'Force de dérive latérale avant', 'Force de dérive latérale arrière')

            plot(app.UIAxes9, Delta_f * pi / 180, Ff_sat, Delta_r * pi / 180, Fr_sat)
            xlabel(app.UIAxes9, 'Angle de dérive (rad)')
            ylabel(app.UIAxes9, 'Force de dérive latérale (N)')
            title(app.UIAxes9, 'Force de dérive latérale avant et arrière avec saturation')
            legend(app.UIAxes9, 'Force de dérive latérale avant saturée', 'Force de derive latérale arrière saturée')

            if DA(end)>0
                app.SousvirageLamp.Color= [1, 0, 0];
            end
            if DA(end)<0
                app.SurvirageLamp.Color= [1, 0, 0];
            end

            function [Matrice, Vecteur] = Coefficient(Masse, MomentInertieLacet, VitesseLineaireG, EmpattementAvant, EmpattementArriere, RigiditeDriveAvant, RigiditeDriveArriere)

                a11 = -((RigiditeDriveAvant * (EmpattementAvant^2) + RigiditeDriveArriere * (EmpattementArriere^2)) / (VitesseLineaireG * MomentInertieLacet));
                a12 = (RigiditeDriveArriere * EmpattementArriere - RigiditeDriveAvant * EmpattementAvant) / MomentInertieLacet;
                a21 = ((RigiditeDriveArriere * EmpattementArriere - RigiditeDriveAvant * EmpattementAvant) / (Masse * VitesseLineaireG^2))-1;
                a22 = -((RigiditeDriveArriere + RigiditeDriveAvant) / (Masse * VitesseLineaireG));

                Matrice = [a11, a12; a21, a22];

                b1 = RigiditeDriveAvant * EmpattementAvant / MomentInertieLacet;
                b2 = RigiditeDriveAvant / (Masse * VitesseLineaireG);

                Vecteur = [b1; b2];
            end

            function [AccelerationLacet_1, VitesseLacet_1, AngleLacet_1, VitesseDeriveGlobal_1, AngleDeriveGlobal_1] = AngleLacetDerive(VitesseLacet_0, AngleLacet_0, AngleDeriveGlobal_0, AngleBraquage, matrice, vecteur, pas)
                AccelerationLacet_1 = matrice(1, 1) * VitesseLacet_0 + matrice(1, 2) * AngleDeriveGlobal_0 + vecteur(1) * AngleBraquage;
                VitesseLacet_1 = VitesseLacet_0 + pas * AccelerationLacet_1;
                AngleLacet_1 = AngleLacet_0 + pas * VitesseLacet_1;

                VitesseDeriveGlobal_1 = matrice(2, 1) * VitesseLacet_0 + matrice(2, 2) * AngleDeriveGlobal_0 + vecteur(2) * AngleBraquage;
                AngleDeriveGlobal_1 = AngleDeriveGlobal_0 + pas * VitesseDeriveGlobal_1;
            end

            function [AngleDeriveArriere_1, AngleDeriveAvant_1] = AngleDeriveAvantArriere(AngleDeriveGlobal_1, VitesseLacet_1, AngleBraquage, VitesseLineaireG, EmpattementAvant, EmpattementArriere)
                AngleDeriveAvant_1 = AngleDeriveGlobal_1 - AngleBraquage + (EmpattementAvant * VitesseLacet_1 / VitesseLineaireG);
                AngleDeriveArriere_1 = AngleDeriveGlobal_1 - (EmpattementArriere * VitesseLacet_1 / VitesseLineaireG);
            end

            function [PositionGx_0, PositionGy_0, PositionAx_0, PositionAy_0] = Position(PositionBx_0, PositionBy_0, AngleLacet_0, AngleDeriveAvant_0, AngleDeriveGlobal_0, EmpattementAvant, EmpattementArriere)
                PositionGx_0 = PositionBx_0 + EmpattementArriere * cos(AngleLacet_0 + AngleDeriveGlobal_0);
                PositionGy_0 = PositionBy_0 + EmpattementArriere * sin(AngleLacet_0 + AngleDeriveGlobal_0);

                PositionAx_0 = PositionBx_0 + (EmpattementAvant + EmpattementArriere) * cos(AngleLacet_0 - AngleDeriveAvant_0);
                PositionAy_0 = PositionBy_0 + (EmpattementAvant + EmpattementArriere) * sin(AngleLacet_0 - AngleDeriveAvant_0);
            end

            function [VitesseGx_1, VitesseGy_1] = VitesseEnG(VitesseLineaireG, AngleDeriveGlobal_1)
                VitesseGx_1 = VitesseLineaireG * cos(AngleDeriveGlobal_1);
                VitesseGy_1 = VitesseLineaireG * sin(AngleDeriveGlobal_1);
            end

            function [VitesseAX_1, VitesseAY_1, VitesseBX_1, VitesseBY_1] = VitesseAvantArriere(VitesseLineaireG, AngleDeriveGlobal_1, AngleDeriveAvant_1, AngleDeriveArriere_1, AngleBraquage, VitesseLacet_1, EmpattementAvant, EmpattementArriere)
                VitesseAX_1 = (VitesseLineaireG * cos(AngleDeriveGlobal_1)) / (cos(AngleDeriveAvant_1 + AngleBraquage));
                VitesseAY_1 = (VitesseLineaireG * sin(AngleDeriveGlobal_1) + EmpattementAvant * VitesseLacet_1) / (sin(AngleDeriveAvant_1 + AngleBraquage));

                VitesseBX_1 = (VitesseLineaireG * cos(AngleDeriveGlobal_1)) / (cos(AngleDeriveArriere_1));
                VitesseBY_1 = (VitesseLineaireG * sin(AngleDeriveGlobal_1) - EmpattementArriere * VitesseLacet_1) / (sin(AngleDeriveArriere_1));
            end
        end

        % Callback function
        function VirageNormaleCheckBoxValueChanged(app, event)
            value = app.VirageNormaleCheckBox.Value;
            app.VirageNormale = value;
        end

        % Selection changed function: SituationSouhaiteButtonGroup
        function SituationSouhaiteButtonGroupSelectionChanged(app, event)
            % app.selectedButton = app.SituationSouhaiteButtonGroup.SelectedObject;
            % if app.selectedButton == app.VirageNormalButton
            %     app.selectedState=1;
            % elseif app.selectedButton == app.FreinageBrusqueButton
            %     app.selectedState=2;
            % elseif app.selectedButton == app.GuidonnageButton
            %     app.selectedState=3;
            % elseif app.selectedButton == app.EvitementdobstaclegaucheButton
            %     app.selectedState=4;
            % elseif app.selectedButton == app.VitesseconstanteButton
            %     app.selectedState=5;
            
        end

        % Selection changed function: OptionvehiculeButtonGroup
        function OptionvehiculeButtonGroupSelectionChanged(app, event)
            %selectedButton = app.OptionvehiculeButtonGroup.SelectedObject;
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes); % Efface le contenu de l'axe "axes1"
            cla(app.UIAxes2); % Efface le contenu de l'axe "axes1"
            cla(app.UIAxes3); % Efface le contenu de l'axe "axes1"
            cla(app.UIAxes4); % Efface le contenu de l'axe "axes1"
            cla(app.UIAxes5); % Efface le contenu de l'axe "axes1"
            % cla(app.UIAxes6); % Efface le contenu de l'axe "axes1"
            % cla(app.UIAxes7); % Efface le contenu de l'axe "axes1"
            % cla(app.UIAxes8); % Efface le contenu de l'axe "axes1"
            cla(app.UIAxes9); % Efface le contenu de l'axe "axes1"
            cla(app.UIAxes10); % Efface le contenu de l'axe "axes1"
        end

        % Selection changed function: ConditionRouteButtonGroup
        function ConditionRouteButtonGroupSelectionChanged(app, event)

        end

        % Callback function
        function AngledebraquagevolantSliderValueChanged(app, event)
            value = app.AngledebraquagevolantSlider.Value;
            app.Angledebraquagevolant = value;
            app.AngledebraquagevolantEditField.Value = value;
            app.SliderAngleBraquage.Value = value;
        end

        % Value changed function: PasdetempsensEditField
        function PasdetempsensEditFieldValueChanged(app, event)
            value = app.PasdetempsensEditField.Value;
            app.dt = value;
        end

        % Value changed function: DuredelasimulationensEditField
        function DuredelasimulationensEditFieldValueChanged(app, event)
            value = app.DuredelasimulationensEditField.Value;
            app.Tf = value;
        end

        % Value changed function: AngledeBraquageVolantenSlider
        function AngledeBraquageVolantenSliderValueChanged(app, event)
            valueA = app.AngledeBraquageVolantenSlider.Value;
            valueB = valueA * 20; % Multiplication par le facteur 2 (échelle différente)
            app.AngledeBraquagedesrouesenSlider.Value = valueB;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            liststr={"Angle de dérive global et vitesse angulaire lacet";"Angle de dérive avant, arrière et global";"Position des roues et du centre de gravité";
                'Vitesse d''entrée';'Angle de braquage en entrée';"Différence des angles de dérive avant et arrière";
                "Force de dérive latérale avant et arrière avec saturation"};
            [s,v]=listdlg('PromptString','Sélectionner une figure à enregistrer','SelectionMode','single','ListSize',[400,250],'ListString',liststr);

            if s==1
                exportgraphics(app.UIAxes,'anglederiveglobal_vitesseangulairelacet.png')
            end
            if s==2
                exportgraphics(app.UIAxes2,'angle_derive.png')
            end
            if s==3
                exportgraphics(app.UIAxes10,'position_roues.png')
            end
            if s==4
                exportgraphics(app.UIAxes3,'vitesse_entree.png')
            end
            if s==5
                exportgraphics(app.UIAxes4,'angle_braquage_entree.png')
            end
            if s==6
                exportgraphics(app.UIAxes5,'diff_anglederive.png')
            end
            if s==7
                exportgraphics(app.UIAxes9,'force_derive_sat.png')
            end

            h=msgbox("Figure enregistrée");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes9 = uiaxes(app.UIFigure);
            title(app.UIAxes9, 'Title')
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Y')
            zlabel(app.UIAxes9, 'Z')
            app.UIAxes10 = uiaxes(app.UIFigure);
            title(app.UIAxes10, 'Title')
            xlabel(app.UIAxes10, 'X')
            ylabel(app.UIAxes10, 'Y')
            zlabel(app.UIAxes10, 'Z')
            app.OptionvehiculeButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionvehiculeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionvehiculeButtonGroupSelectionChanged, true);
            app.OptionvehiculeButtonGroup.Title = 'Option vehicule';
            app.Image = uiimage(app.OptionvehiculeButtonGroup);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'quadmf400H.png');
            app.Image2 = uiimage(app.OptionvehiculeButtonGroup);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'mercedesclasseS.jpg');
            app.QuadMF400HButton = uiradiobutton(app.OptionvehiculeButtonGroup);
            app.QuadMF400HButton.Text = 'Quad MF400H';
            app.QuadMF400HButton.Value = true;
            app.MercedesClasseSButton = uiradiobutton(app.OptionvehiculeButtonGroup);
            app.MercedesClasseSButton.Text = 'Mercedes Classe S';
            app.ParamtresdelasimulationPanel = uipanel(app.UIFigure);
            app.ParamtresdelasimulationPanel.Title = 'Paramètres de la simulation';
            app.VitesseenkmhLabel = uilabel(app.ParamtresdelasimulationPanel);
            app.VitesseenkmhLabel.Text = {'Vitesse'; 'en km/h'};
            app.VitesseenkmhSlider = uislider(app.ParamtresdelasimulationPanel);
            app.VitesseenkmhSlider.Limits = [0 140];
            app.VitesseenkmhSlider.MajorTicks = [0 20 40 60 80 100 120 140];
            app.VitesseenkmhSlider.ValueChangedFcn = createCallbackFcn(app, @VitesseenkmhSliderValueChanged, true);
            app.AngledeBraquagedesrouesenSliderLabel = uilabel(app.ParamtresdelasimulationPanel);
            app.AngledeBraquagedesrouesenSliderLabel.Text = {'Angle de Braquage'; 'des roues en °'};
            app.AngledeBraquagedesrouesenSlider = uislider(app.ParamtresdelasimulationPanel);
            app.AngledeBraquagedesrouesenSlider.Limits = [-20 20];
            app.AngledeBraquagedesrouesenSlider.ValueChangedFcn = createCallbackFcn(app, @AngledeBraquagedesrouesenSliderValueChanged, true);
            app.AngledeBraquageVolantenSliderLabel = uilabel(app.ParamtresdelasimulationPanel);
            app.AngledeBraquageVolantenSliderLabel.Text = {'Angle de Braquage'; 'Volant en °'};
            app.AngledeBraquageVolantenSlider = uislider(app.ParamtresdelasimulationPanel);
            app.AngledeBraquageVolantenSlider.Limits = [-360 360];
            app.AngledeBraquageVolantenSlider.ValueChangedFcn = createCallbackFcn(app, @AngledeBraquageVolantenSliderValueChanged, true);
            app.SituationSouhaiteButtonGroup = uibuttongroup(app.ParamtresdelasimulationPanel);
            app.SituationSouhaiteButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SituationSouhaiteButtonGroupSelectionChanged, true);
            app.SituationSouhaiteButtonGroup.Title = 'Situation Souhaitée';
            app.VirageNormalButton = uiradiobutton(app.SituationSouhaiteButtonGroup);
            app.VirageNormalButton.Text = 'Virage Normal';
            app.VirageNormalButton.Value = true;
            app.FreinageBrusqueButton = uiradiobutton(app.SituationSouhaiteButtonGroup);
            app.FreinageBrusqueButton.Text = 'Freinage Brusque ';
            app.EvitementdobstaclegaucheButton = uiradiobutton(app.SituationSouhaiteButtonGroup);
            app.EvitementdobstaclegaucheButton.Text = 'Evitement d''obstacle gauche';
            app.GuidonnageButton = uiradiobutton(app.SituationSouhaiteButtonGroup);
            app.GuidonnageButton.Text = 'Guidonnage';
            app.VitesseconstanteButton = uiradiobutton(app.SituationSouhaiteButtonGroup);
            app.VitesseconstanteButton.Text = 'Vitesse constante';
            app.ConditionRouteButtonGroup = uibuttongroup(app.ParamtresdelasimulationPanel);
            app.ConditionRouteButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ConditionRouteButtonGroupSelectionChanged, true);
            app.ConditionRouteButtonGroup.Title = 'Condition Route';
            app.SecButton = uiradiobutton(app.ConditionRouteButtonGroup);
            app.SecButton.Text = 'Sec';
            app.SecButton.Value = true;
            app.MouillButton = uiradiobutton(app.ConditionRouteButtonGroup);
            app.MouillButton.Text = 'Mouillé';
            app.NeigeButton = uiradiobutton(app.ConditionRouteButtonGroup);
            app.NeigeButton.Text = 'Neige';
            app.VerglasButton = uiradiobutton(app.ConditionRouteButtonGroup);
            app.VerglasButton.Text = 'Verglas';
            app.PasdetempsensEditFieldLabel = uilabel(app.ParamtresdelasimulationPanel);
            app.PasdetempsensEditFieldLabel.Text = 'Pas de temps (en s)';
            app.PasdetempsensEditField = uieditfield(app.ParamtresdelasimulationPanel, 'numeric');
            app.PasdetempsensEditField.ValueChangedFcn = createCallbackFcn(app, @PasdetempsensEditFieldValueChanged, true);
            app.DuredelasimulationensEditFieldLabel = uilabel(app.ParamtresdelasimulationPanel);
            app.DuredelasimulationensEditFieldLabel.Text = 'Durée de la simulation (en s)';
            app.DuredelasimulationensEditField = uieditfield(app.ParamtresdelasimulationPanel, 'numeric');
            app.DuredelasimulationensEditField.ValueChangedFcn = createCallbackFcn(app, @DuredelasimulationensEditFieldValueChanged, true);
            app.SousvirageLampLabel = uilabel(app.ParamtresdelasimulationPanel);
            app.SousvirageLampLabel.Text = 'Sous virage';
            app.SousvirageLamp = uilamp(app.ParamtresdelasimulationPanel);
            app.SurvirageLampLabel = uilabel(app.ParamtresdelasimulationPanel);
            app.SurvirageLampLabel.Text = 'Sur virage';
            app.SurvirageLamp = uilamp(app.ParamtresdelasimulationPanel);
            app.ClearButton = uibutton(app.ParamtresdelasimulationPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.Button = uibutton(app.ParamtresdelasimulationPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'pngegg.png');
            app.Button.Text = '';
            app.PlayButton = uibutton(app.ParamtresdelasimulationPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Simu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end